classdef ZmapSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZMAPSettingsUIFigure           matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        DirectoriesPanel               matlab.ui.container.Panel
        outputEditFieldLabel           matlab.ui.control.Label
        outputEditField                matlab.ui.control.EditField
        dataEditFieldLabel             matlab.ui.control.Label
        dataEditField                  matlab.ui.control.EditField
        workingEditFieldLabel          matlab.ui.control.Label
        workingEditField               matlab.ui.control.EditField
        ParallelProcessingPanel        matlab.ui.container.Panel
        EnableParallelProcessingCheckBox  matlab.ui.control.CheckBox
        ParallelProcessingThreshholdEditFieldLabel  matlab.ui.control.Label
        ParallelProcessingThreshholdEditField  matlab.ui.control.NumericEditField
        DebugModeCheckBox              matlab.ui.control.CheckBox
        CrossSectionsTab               matlab.ui.container.Tab
        CrossSectionsLabel             matlab.ui.control.Label
        CrossSectionsPanel             matlab.ui.container.Panel
        XsectionWidthkmEditFieldLabel  matlab.ui.control.Label
        XsecWidthEditField             matlab.ui.control.NumericEditField
        ColorDropDownLabel             matlab.ui.control.Label
        XsecColorDropDown              matlab.ui.control.DropDown
        LineWidthEditFieldLabel        matlab.ui.control.Label
        XsecLineWidthEditField         matlab.ui.control.NumericEditField
        LineStyleDropDownLabel         matlab.ui.control.Label
        XsecLineStyleDropDown          matlab.ui.control.DropDown
        EndMarkerDropDownLabel         matlab.ui.control.Label
        XsecEndMarkerDropDown          matlab.ui.control.DropDown
        MarkerSizeEditFieldLabel       matlab.ui.control.Label
        XsecMarkerSizeEditField        matlab.ui.control.NumericEditField
        LabelsPanel                    matlab.ui.container.Panel
        FontSizeEditFieldLabel         matlab.ui.control.Label
        XsecLabelFontSizeEditField     matlab.ui.control.NumericEditField
        BackgroundColorDropDownLabel   matlab.ui.control.Label
        XsecLabelBackgroundColorDropDown  matlab.ui.control.DropDown
        XsecLabelOutlineCheckBox       matlab.ui.control.CheckBox
        XsecLabelBoldCheckBox          matlab.ui.control.CheckBox
        BorderPanel                    matlab.ui.container.Panel
        LineStyleDropDown_2Label       matlab.ui.control.Label
        XsecBorderLineStyleDropDown    matlab.ui.control.DropDown
        LineWidthEditField_2Label      matlab.ui.control.Label
        XsecBorderLineWidthEditField   matlab.ui.control.NumericEditField
        TabPanel                       matlab.ui.container.Panel
        ColorDropDown_2Label           matlab.ui.control.Label
        XsecTabColorDropDown           matlab.ui.control.DropDown
        CatalogsTab                    matlab.ui.container.Tab
        MainMapTab                     matlab.ui.container.Tab
        MainTabGroup                   matlab.ui.container.TabGroup
        DisplayOptionsTab              matlab.ui.container.Tab
        FeaturesTab                    matlab.ui.container.Tab
        AddFeatureButton               matlab.ui.control.Button
        FeaturesListBoxLabel           matlab.ui.control.Label
        FeaturesListBox                matlab.ui.control.ListBox
        Panel                          matlab.ui.container.Panel
        FeatureUseCheckBox             matlab.ui.control.CheckBox
        FeatureIDLabel                 matlab.ui.control.Label
        DetailLevelDropDownLabel       matlab.ui.control.Label
        FeatureDetailLevelDropDown     matlab.ui.control.DropDown
        FeatureHitTestCheckBox         matlab.ui.control.CheckBox
        FeatureLinePanel               matlab.ui.container.Panel
        WidthEditFieldLabel            matlab.ui.control.Label
        FeatureLineWidthEditField      matlab.ui.control.NumericEditField
        ColorEditFieldLabel            matlab.ui.control.Label
        FeatureLineColorEditField      matlab.ui.control.EditField
        StyleLabel                     matlab.ui.control.Label
        FeatureLineStyleDropDown       matlab.ui.control.DropDown
        FeatureMarkerPanel             matlab.ui.container.Panel
        MarkerLabel_2                  matlab.ui.control.Label
        FeatureMarkerDropDown          matlab.ui.control.DropDown
        SizeLabel                      matlab.ui.control.Label
        FeatureMarkerSizeEditField     matlab.ui.control.NumericEditField
        FaceColorEditFieldLabel        matlab.ui.control.Label
        FeatureFaceColorEditField      matlab.ui.control.EditField
        EdgeColorEditFieldLabel        matlab.ui.control.Label
        FeatureEdgeColorEditField      matlab.ui.control.EditField
        TagEditFieldLabel              matlab.ui.control.Label
        FeatureTagEditField            matlab.ui.control.EditField
        PickablePartsDropDownLabel     matlab.ui.control.Label
        FeaturePickablePartsDropDown   matlab.ui.control.DropDown
        DisplayNameEditFieldLabel      matlab.ui.control.Label
        FeatureDisplayNameEditField    matlab.ui.control.EditField
        FeatureUIAxes                  matlab.ui.control.UIAxes
        ResultsTab                     matlab.ui.container.Tab
        SamplingTab                    matlab.ui.container.Tab
        RadiusEditField_2Label         matlab.ui.control.Label
        SampleRadiusEditField          matlab.ui.control.NumericEditField
        DefaultsTab                    matlab.ui.container.Tab
        GridTab                        matlab.ui.container.Tab
        GridAnchorPointPanel           matlab.ui.container.Panel
        XorLonEditFieldLabel           matlab.ui.control.Label
        GridXAnchor                    matlab.ui.control.NumericEditField
        YorLatEditFieldLabel           matlab.ui.control.Label
        GridYAnchor                    matlab.ui.control.NumericEditField
        ZdownEditFieldLabel            matlab.ui.control.Label
        GridZAnchor                    matlab.ui.control.NumericEditField
        GridSeparationPanel            matlab.ui.container.Panel
        dxEditFieldLabel               matlab.ui.control.Label
        Griddx                         matlab.ui.control.NumericEditField
        dyEditFieldLabel               matlab.ui.control.Label
        Griddy                         matlab.ui.control.NumericEditField
        dzkmLabel                      matlab.ui.control.Label
        Griddz                         matlab.ui.control.NumericEditField
        UnitsDropDownLabel             matlab.ui.control.Label
        GridUnitsDropDown              matlab.ui.control.DropDown
        GridFollowMeridiansCheckBox    matlab.ui.control.CheckBox
        GridOKButton                   matlab.ui.control.Button
        GridLoadButton                 matlab.ui.control.Button
        GridSaveOKButton               matlab.ui.control.Button
        GridNameEditFieldLabel         matlab.ui.control.Label
        GridNameEditField              matlab.ui.control.EditField
        GridDefaultsLabel              matlab.ui.control.Label
        GridAppearencePanel            matlab.ui.container.Panel
        ColorDropDownLabel_2           matlab.ui.control.Label
        GridColorDropDown              matlab.ui.control.DropDown
        MarkerLabel                    matlab.ui.control.Label
        GridMarkerDropDown             matlab.ui.control.DropDown
        LineWidthEditFieldLabel_2      matlab.ui.control.Label
        GridLineWidthEditField         matlab.ui.control.NumericEditField
        MarkerSizeEditFieldLabel_2     matlab.ui.control.Label
        GridMarkerSizeEditField        matlab.ui.control.NumericEditField
        UseFixedAnchorPointCheckBox    matlab.ui.control.CheckBox
        SaveButton                     matlab.ui.control.Button
        RevertTabButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        DefaultXsec = defaults.readDefaults('cross_section') % Values for Cross Sections from disk
        ModifiedFieldBackgroundColor = [1.0 1.0 0.9]; % Color for fields that have been changed
    end
    
    methods (Access = private)
        
        function results = saveDefaults(app)
            
            % cross section defaults
            results=struct();
            results.WidthKm = app.XsecWidthEditField.Value;
            results.LineProps = struct(...
                'Marker', app.XsecEndMarkerDropDown.Value,...
                'LineWidth', app.XsecLineWidthEditField.Value,...
                'LineStyle', app.XsecLineStyleDropDown.Value,...
                'Color',app.XsecColorDropDown.Value,...
                'MarkerSize', app.XsecMarkerSizeEditField.Value);
            
            results.LabelProps = struct('FontWeight',app.checkbox2option(app.XsecLabelBoldCheckBox,'bold','normal'),...
                'FontSize',app.XsecLabelFontSizeEditField.Value,...
                'BackgroundColor',app.XsecLabelBackgroundColorDropDown.Value,...
                'EdgeColor',app.checkbox2option(app.XsecLabelOutlineCheckBox,app.XsecColorDropDown.Value,'none')...
                );
            results.TabProps = struct(...
                'Color',app.XsecTabColorDropDown.Value...
                );
            results.BorderProps = struct(...
                'LineStyle',app.XsecBorderLineStyleDropDown.Value,...
                'LineWidth',app.XsecBorderLineWidthEditField.Value...
                );
            defaults.writeDefaults('cross_section',results);


        end
        
        function results = setFromDefaults(app)
            
            %%  cross section defaults
            results=defaults.readDefaults('cross_section');
            
            app.XsecWidthEditField.Value = results.WidthKm;
            
            app.XsecEndMarkerDropDown.Value = results.LineProps.Marker;
            app.XsecLineWidthEditField.Value = results.LineProps.LineWidth;
            app.XsecLineStyleDropDown.Value = results.LineProps.LineStyle;
            app.XsecColorDropDown.Value = results.LineProps.Color;
            app.XsecMarkerSizeEditField.Value = results.LineProps.MarkerSize;
            
            app.XsecLabelBoldCheckBox.Value = logical(matlab.lang.OnOffSwitchState(results.LabelProps.FontWeight=="bold"));
            app.XsecLabelFontSizeEditField.Value = results.LabelProps.FontSize;
            app.XsecLabelBackgroundColorDropDown.Value = results.LabelProps.BackgroundColor;
            app.XsecLabelOutlineCheckBox.Value = logical(matlab.lang.OnOffSwitchState(results.LabelProps.EdgeColor~="none"));
            
            app.XsecTabColorDropDown.Value = results.TabProps.Color;
            
            app.XsecBorderLineStyleDropDown.Value = results.BorderProps.LineStyle;
            app.XsecBorderLineWidthEditField.Value = results.BorderProps.LineWidth;
            
        end
        
        function val=checkbox2option(app,checkcontrol, iftrue, iffalse)
            if checkcontrol.Value
                val=iftrue;
            else
                val=iffalse;
            end
        end
        
        function revertValues(app,targetTab)
            disp("Reverting TAB:[" + targetTab.Title + "] (if the code were in place)");
            modified=[findobj(targetTab,'BackgroundColor',app.ModifiedFieldBackgroundColor) ;...
                findobj(targetTab,'Type','uicheckbox','-and','FontWeight','bold')];
            disp(modified);
            for j=1:numel(modified)
                event.Value=modified(j).UserData;
                event.PreviousValue=modified(j).Value;
                event.Source = modified(j);
                event.Name='ValueChanged';
                modified(j).Value=modified(j).UserData;
                modified(j).ValueChangedFcn(app,event);
            end
        end
        
        function results = showFeature(app,featurekey)
            app.FeatureIDLabel.Text = featurekey;
            feature = ZmapGlobal.Data.features(featurekey);
            plotdefault = feature.PlottingDefaults;
            app.FeatureDisplayNameEditField.Value = plotdefault.DisplayName;
            app.FeatureTagEditField.Value = plotdefault.Tag;
            app.FeatureLineWidthEditField.Value = plotdefault.LineWidth;
            app.FeatureLineStyleDropDown.Value = plotdefault.LineStyle;
            if ischar(plotdefault.Color)
                app.FeatureLineColorEditField.Value = plotdefault.Color;
            else
                app.FeatureLineColorEditField.Value = mat2str(plotdefault.Color);
            end
            if ~ismember(plotdefault.Marker,app.FeatureMarkerDropDown.Items)
                idx=startsWith(app.FeatureMarkerDropDown.Items,plotdefault.Marker)
                app.FeatureMarkerDropDown.Value=app.FeatureMarkerDropDown.Items(idx);
            else
                app.FeatureMarkerDropDown.Value = plotdefault.Marker;
            end
            app.FeatureMarkerSizeEditField.Value = plotdefault.MarkerSize;
            if ischar(plotdefault.MarkerFaceColor)
                app.FeatureFaceColorEditField.Value = plotdefault.MarkerFaceColor;
            else
                app.FeatureFaceColorEditField.Value = mat2str(plotdefault.MarkerFaceColor);
            end
            if ischar(plotdefault.MarkerEdgeColor)
                app.FeatureEdgeColorEditField.Value = plotdefault.MarkerEdgeColor;
            else
                app.FeatureEdgeColorEditField.Value = mat2str(plotdefault.MarkerEdgeColor);
            end
            app.FeaturePickablePartsDropDown.Value = plotdefault.PickableParts;
            app.FeatureHitTestCheckBox.Value = plotdefault.HitTest=="on";
            %f=fields(plotdefault);
            h=findobj(app.FeatureUIAxes.Children,'Type','Line');
            set(h,plotdefault);
            %for i=1:numel(f)
            %    set(h,f{i},plotdefault.f{i});
            %end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setFromDefaults();
            
            % plot example for feature
            line(app.FeatureUIAxes,[1;2;3;4],[0 .5 .75 .6],'Tag','FeatureExample');
            app.FeatureUIAxes.XLim=[0 5];
            app.FeatureUIAxes.YTickLabel={};
            app.FeatureUIAxes.XTickLabel={};
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.saveDefaults();
        end

        % Value changed function: XsecBorderLineStyleDropDown, 
        % XsecBorderLineWidthEditField, XsecEndMarkerDropDown, 
        % XsecLabelBoldCheckBox, XsecLabelFontSizeEditField, 
        % XsecLabelOutlineCheckBox, XsecLineStyleDropDown, 
        % XsecLineWidthEditField, XsecMarkerSizeEditField, 
        % XsecWidthEditField
        function valueChanged(app, event)
            % change color when value is different from the saved value
            value = app.XsecWidthEditField.Value;
            if isempty(event.Source.UserData)
                event.Source.UserData=event.PreviousValue;
            end
            if string(event.Source.Value) ~= string(event.Source.UserData)
                if event.Source.Type ~= "uicheckbox"
                    event.Source.BackgroundColor=app.ModifiedFieldBackgroundColor;
                else
                    event.Source.FontWeight='bold';
                end
            else
                if event.Source.Type ~= "uicheckbox"
                    event.Source.BackgroundColor=[1 1 1];
                else
                    event.Source.FontWeight='normal';
                end
            end
        end

        % Button pushed function: RevertTabButton
        function RevertTabButtonPushed(app, event)
            app.revertValues(app.TabGroup.SelectedTab);
        end

        % Button pushed function: AddFeatureButton
        function AddFeatureButtonPushed(app, event)
            n = size(app.FeaturesUITable.Data,1)
            c = numel(app.FeaturesUITable.ColumnName)
            if isempty(app.FeaturesUITable.Data)
                app.FeaturesUITable.Data=cell(1,c);
            end
            app.FeaturesUITable.Data(n+1,1:c)={'-'};
        end

        % Value changed function: FeaturesListBox
        function FeaturesListBoxValueChanged(app, event)
            value = app.FeaturesListBox.Value;
            app.showFeature(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZMAPSettingsUIFigure = uifigure;
            app.ZMAPSettingsUIFigure.Name = 'ZMAP Settings';
            app.TabGroup = uitabgroup(app.ZMAPSettingsUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.DirectoriesPanel = uipanel(app.GeneralTab);
            app.DirectoriesPanel.Title = 'Directories';
            app.outputEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.outputEditFieldLabel.Text = 'output';
            app.outputEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.dataEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.dataEditFieldLabel.Text = 'data';
            app.dataEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.workingEditFieldLabel = uilabel(app.DirectoriesPanel);
            app.workingEditFieldLabel.Text = 'working';
            app.workingEditField = uieditfield(app.DirectoriesPanel, 'text');
            app.ParallelProcessingPanel = uipanel(app.GeneralTab);
            app.ParallelProcessingPanel.Title = 'Parallel Processing';
            app.EnableParallelProcessingCheckBox = uicheckbox(app.ParallelProcessingPanel);
            app.EnableParallelProcessingCheckBox.Text = 'Enable';
            app.ParallelProcessingThreshholdEditFieldLabel = uilabel(app.ParallelProcessingPanel);
            app.ParallelProcessingThreshholdEditFieldLabel.Text = 'Parallel Processing Threshhold';
            app.ParallelProcessingThreshholdEditField = uieditfield(app.ParallelProcessingPanel, 'numeric');
            app.DebugModeCheckBox = uicheckbox(app.GeneralTab);
            app.DebugModeCheckBox.Text = 'Debug Mode';
            app.CrossSectionsTab = uitab(app.TabGroup);
            app.CrossSectionsTab.Title = 'Cross Sections';
            app.CrossSectionsLabel = uilabel(app.CrossSectionsTab);
            app.CrossSectionsLabel.Text = 'Cross Sections';
            app.CrossSectionsPanel = uipanel(app.CrossSectionsTab);
            app.CrossSectionsPanel.Title = 'Cross Sections';
            app.XsectionWidthkmEditFieldLabel = uilabel(app.CrossSectionsPanel);
            app.XsectionWidthkmEditFieldLabel.Text = 'X-section Width [km]';
            app.XsecWidthEditField = uieditfield(app.CrossSectionsPanel, 'numeric');
            app.XsecWidthEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecWidthEditField.Value = 20;
            app.ColorDropDownLabel = uilabel(app.CrossSectionsPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.XsecColorDropDown = uidropdown(app.CrossSectionsPanel);
            app.XsecColorDropDown.Items = {'AUTO', 'USER DEFINED'};
            app.XsecColorDropDown.Editable = 'on';
            app.XsecColorDropDown.Value = 'AUTO';
            app.LineWidthEditFieldLabel = uilabel(app.CrossSectionsPanel);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.XsecLineWidthEditField = uieditfield(app.CrossSectionsPanel, 'numeric');
            app.XsecLineWidthEditField.LowerLimitInclusive = 'off';
            app.XsecLineWidthEditField.UpperLimitInclusive = 'off';
            app.XsecLineWidthEditField.Limits = [0 Inf];
            app.XsecLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLineWidthEditField.Value = 3;
            app.LineStyleDropDownLabel = uilabel(app.CrossSectionsPanel);
            app.LineStyleDropDownLabel.Text = 'Line Style';
            app.XsecLineStyleDropDown = uidropdown(app.CrossSectionsPanel);
            app.XsecLineStyleDropDown.Items = {'-', '--', ':', '-.', 'none'};
            app.XsecLineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLineStyleDropDown.Value = '--';
            app.EndMarkerDropDownLabel = uilabel(app.CrossSectionsPanel);
            app.EndMarkerDropDownLabel.Text = 'End Marker';
            app.XsecEndMarkerDropDown = uidropdown(app.CrossSectionsPanel);
            app.XsecEndMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.XsecEndMarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecEndMarkerDropDown.Value = 'x';
            app.MarkerSizeEditFieldLabel = uilabel(app.CrossSectionsPanel);
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
            app.XsecMarkerSizeEditField = uieditfield(app.CrossSectionsPanel, 'numeric');
            app.XsecMarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecMarkerSizeEditField.Value = 10;
            app.LabelsPanel = uipanel(app.CrossSectionsTab);
            app.LabelsPanel.Title = 'Labels';
            app.FontSizeEditFieldLabel = uilabel(app.LabelsPanel);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.XsecLabelFontSizeEditField = uieditfield(app.LabelsPanel, 'numeric');
            app.XsecLabelFontSizeEditField.LowerLimitInclusive = 'off';
            app.XsecLabelFontSizeEditField.UpperLimitInclusive = 'off';
            app.XsecLabelFontSizeEditField.Limits = [0 Inf];
            app.XsecLabelFontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLabelFontSizeEditField.Value = 12;
            app.BackgroundColorDropDownLabel = uilabel(app.LabelsPanel);
            app.BackgroundColorDropDownLabel.Text = 'Background Color';
            app.XsecLabelBackgroundColorDropDown = uidropdown(app.LabelsPanel);
            app.XsecLabelBackgroundColorDropDown.Items = {'NONE', 'white', 'USER DEFINED'};
            app.XsecLabelBackgroundColorDropDown.Value = 'NONE';
            app.XsecLabelOutlineCheckBox = uicheckbox(app.LabelsPanel);
            app.XsecLabelOutlineCheckBox.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLabelOutlineCheckBox.Text = 'Outline';
            app.XsecLabelBoldCheckBox = uicheckbox(app.LabelsPanel);
            app.XsecLabelBoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecLabelBoldCheckBox.Text = 'Bold';
            app.BorderPanel = uipanel(app.CrossSectionsTab);
            app.BorderPanel.Title = 'Border';
            app.LineStyleDropDown_2Label = uilabel(app.BorderPanel);
            app.LineStyleDropDown_2Label.Text = 'Line Style';
            app.XsecBorderLineStyleDropDown = uidropdown(app.BorderPanel);
            app.XsecBorderLineStyleDropDown.Items = {'-', '--', ':', '-.', 'none'};
            app.XsecBorderLineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecBorderLineStyleDropDown.Value = '-';
            app.LineWidthEditField_2Label = uilabel(app.BorderPanel);
            app.LineWidthEditField_2Label.Text = 'Line Width';
            app.XsecBorderLineWidthEditField = uieditfield(app.BorderPanel, 'numeric');
            app.XsecBorderLineWidthEditField.LowerLimitInclusive = 'off';
            app.XsecBorderLineWidthEditField.UpperLimitInclusive = 'off';
            app.XsecBorderLineWidthEditField.Limits = [0 Inf];
            app.XsecBorderLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @valueChanged, true);
            app.XsecBorderLineWidthEditField.Value = 0.25;
            app.TabPanel = uipanel(app.CrossSectionsTab);
            app.TabPanel.Title = 'Tab';
            app.ColorDropDown_2Label = uilabel(app.TabPanel);
            app.ColorDropDown_2Label.Text = 'Color';
            app.XsecTabColorDropDown = uidropdown(app.TabPanel);
            app.XsecTabColorDropDown.Items = {'AUTO', 'Black', 'Darker', 'Lighter', 'USER DEFINED'};
            app.XsecTabColorDropDown.Editable = 'on';
            app.XsecTabColorDropDown.Value = 'Darker';
            app.CatalogsTab = uitab(app.TabGroup);
            app.CatalogsTab.Title = 'Catalogs';
            app.MainMapTab = uitab(app.TabGroup);
            app.MainMapTab.Title = 'Main Map';
            app.MainTabGroup = uitabgroup(app.MainMapTab);
            app.DisplayOptionsTab = uitab(app.MainTabGroup);
            app.DisplayOptionsTab.Title = 'Display Options';
            app.FeaturesTab = uitab(app.MainTabGroup);
            app.FeaturesTab.Title = 'Features';
            app.AddFeatureButton = uibutton(app.FeaturesTab, 'push');
            app.AddFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @AddFeatureButtonPushed, true);
            app.AddFeatureButton.Text = 'AddFeature';
            app.FeaturesListBoxLabel = uilabel(app.FeaturesTab);
            app.FeaturesListBoxLabel.Text = 'Features';
            app.FeaturesListBox = uilistbox(app.FeaturesTab);
            app.FeaturesListBox.Items = {'coastline', 'borders', 'lakes', 'rivers', 'volcanoes', 'plates', 'faults', 'stations'};
            app.FeaturesListBox.ValueChangedFcn = createCallbackFcn(app, @FeaturesListBoxValueChanged, true);
            app.FeaturesListBox.Value = 'coastline';
            app.Panel = uipanel(app.FeaturesTab);
            app.Panel.Title = 'Panel';
            app.FeatureUseCheckBox = uicheckbox(app.Panel);
            app.FeatureUseCheckBox.Text = 'Use Feature';
            app.FeatureIDLabel = uilabel(app.Panel);
            app.FeatureIDLabel.Text = 'FeatureID';
            app.DetailLevelDropDownLabel = uilabel(app.Panel);
            app.DetailLevelDropDownLabel.Text = 'Detail Level';
            app.FeatureDetailLevelDropDown = uidropdown(app.Panel);
            app.FeatureDetailLevelDropDown.Items = {'h', 'l', 'Option 3', 'Option 4'};
            app.FeatureDetailLevelDropDown.Value = 'h';
            app.FeatureHitTestCheckBox = uicheckbox(app.Panel);
            app.FeatureHitTestCheckBox.Enable = 'off';
            app.FeatureHitTestCheckBox.Text = 'HitTest';
            app.FeatureLinePanel = uipanel(app.Panel);
            app.FeatureLinePanel.Title = 'Line';
            app.WidthEditFieldLabel = uilabel(app.FeatureLinePanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.FeatureLineWidthEditField = uieditfield(app.FeatureLinePanel, 'numeric');
            app.ColorEditFieldLabel = uilabel(app.FeatureLinePanel);
            app.ColorEditFieldLabel.Text = 'Color';
            app.FeatureLineColorEditField = uieditfield(app.FeatureLinePanel, 'text');
            app.StyleLabel = uilabel(app.FeatureLinePanel);
            app.StyleLabel.Text = 'Style';
            app.FeatureLineStyleDropDown = uidropdown(app.FeatureLinePanel);
            app.FeatureLineStyleDropDown.Items = {'-', '--', ':', '-.', 'none'};
            app.FeatureLineStyleDropDown.Value = '--';
            app.FeatureMarkerPanel = uipanel(app.Panel);
            app.FeatureMarkerPanel.Title = 'Marker';
            app.MarkerLabel_2 = uilabel(app.FeatureMarkerPanel);
            app.MarkerLabel_2.Text = 'Marker';
            app.FeatureMarkerDropDown = uidropdown(app.FeatureMarkerPanel);
            app.FeatureMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.FeatureMarkerDropDown.Value = 'x';
            app.SizeLabel = uilabel(app.FeatureMarkerPanel);
            app.SizeLabel.Text = 'Size';
            app.FeatureMarkerSizeEditField = uieditfield(app.FeatureMarkerPanel, 'numeric');
            app.FeatureMarkerSizeEditField.Value = 10;
            app.FaceColorEditFieldLabel = uilabel(app.FeatureMarkerPanel);
            app.FaceColorEditFieldLabel.Text = 'Face Color';
            app.FeatureFaceColorEditField = uieditfield(app.FeatureMarkerPanel, 'text');
            app.EdgeColorEditFieldLabel = uilabel(app.FeatureMarkerPanel);
            app.EdgeColorEditFieldLabel.Text = 'Edge Color';
            app.FeatureEdgeColorEditField = uieditfield(app.FeatureMarkerPanel, 'text');
            app.TagEditFieldLabel = uilabel(app.Panel);
            app.TagEditFieldLabel.Enable = 'off';
            app.TagEditFieldLabel.Text = 'Tag';
            app.FeatureTagEditField = uieditfield(app.Panel, 'text');
            app.FeatureTagEditField.Editable = 'off';
            app.FeatureTagEditField.Enable = 'off';
            app.PickablePartsDropDownLabel = uilabel(app.Panel);
            app.PickablePartsDropDownLabel.Enable = 'off';
            app.PickablePartsDropDownLabel.Text = 'PickableParts';
            app.FeaturePickablePartsDropDown = uidropdown(app.Panel);
            app.FeaturePickablePartsDropDown.Items = {'visible', 'none', 'all'};
            app.FeaturePickablePartsDropDown.Enable = 'off';
            app.FeaturePickablePartsDropDown.Value = 'visible';
            app.DisplayNameEditFieldLabel = uilabel(app.Panel);
            app.DisplayNameEditFieldLabel.Text = 'DisplayName';
            app.FeatureDisplayNameEditField = uieditfield(app.Panel, 'text');
            app.FeatureUIAxes = uiaxes(app.Panel);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.SamplingTab = uitab(app.TabGroup);
            app.SamplingTab.Title = 'Sampling';
            app.RadiusEditField_2Label = uilabel(app.SamplingTab);
            app.RadiusEditField_2Label.Text = 'Radius';
            app.SampleRadiusEditField = uieditfield(app.SamplingTab, 'numeric');
            app.DefaultsTab = uitab(app.TabGroup);
            app.DefaultsTab.Title = 'Defaults';
            app.GridTab = uitab(app.TabGroup);
            app.GridTab.Title = 'Grid';
            app.GridAnchorPointPanel = uipanel(app.GridTab);
            app.GridAnchorPointPanel.Title = 'Anchor Point';
            app.XorLonEditFieldLabel = uilabel(app.GridAnchorPointPanel);
            app.XorLonEditFieldLabel.Text = 'X or Lon';
            app.GridXAnchor = uieditfield(app.GridAnchorPointPanel, 'numeric');
            app.GridXAnchor.LowerLimitInclusive = 'off';
            app.GridXAnchor.UpperLimitInclusive = 'off';
            app.YorLatEditFieldLabel = uilabel(app.GridAnchorPointPanel);
            app.YorLatEditFieldLabel.Text = 'Y or Lat';
            app.GridYAnchor = uieditfield(app.GridAnchorPointPanel, 'numeric');
            app.GridYAnchor.LowerLimitInclusive = 'off';
            app.GridYAnchor.UpperLimitInclusive = 'off';
            app.ZdownEditFieldLabel = uilabel(app.GridAnchorPointPanel);
            app.ZdownEditFieldLabel.Enable = 'off';
            app.ZdownEditFieldLabel.Text = 'Z (+down)';
            app.GridZAnchor = uieditfield(app.GridAnchorPointPanel, 'numeric');
            app.GridZAnchor.LowerLimitInclusive = 'off';
            app.GridZAnchor.UpperLimitInclusive = 'off';
            app.GridZAnchor.Enable = 'off';
            app.GridSeparationPanel = uipanel(app.GridTab);
            app.GridSeparationPanel.Title = 'Separation';
            app.dxEditFieldLabel = uilabel(app.GridSeparationPanel);
            app.dxEditFieldLabel.Text = 'dx';
            app.Griddx = uieditfield(app.GridSeparationPanel, 'numeric');
            app.Griddx.LowerLimitInclusive = 'off';
            app.Griddx.Limits = [0 Inf];
            app.Griddx.ValueDisplayFormat = '%.3f';
            app.Griddx.Value = 0.5;
            app.dyEditFieldLabel = uilabel(app.GridSeparationPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.Griddy = uieditfield(app.GridSeparationPanel, 'numeric');
            app.Griddy.LowerLimitInclusive = 'off';
            app.Griddy.Limits = [0 Inf];
            app.Griddy.ValueDisplayFormat = '%.3f';
            app.Griddy.Value = 0.5;
            app.dzkmLabel = uilabel(app.GridSeparationPanel);
            app.dzkmLabel.Enable = 'off';
            app.dzkmLabel.Text = 'dz [km]';
            app.Griddz = uieditfield(app.GridSeparationPanel, 'numeric');
            app.Griddz.LowerLimitInclusive = 'off';
            app.Griddz.Limits = [0 Inf];
            app.Griddz.ValueDisplayFormat = '%.3f';
            app.Griddz.Enable = 'off';
            app.Griddz.Value = 1;
            app.UnitsDropDownLabel = uilabel(app.GridSeparationPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.GridUnitsDropDown = uidropdown(app.GridSeparationPanel);
            app.GridUnitsDropDown.Items = {'Degrees', 'Kilometers'};
            app.GridUnitsDropDown.Value = 'Degrees';
            app.GridFollowMeridiansCheckBox = uicheckbox(app.GridSeparationPanel);
            app.GridFollowMeridiansCheckBox.Text = 'Follow Meridians';
            app.GridOKButton = uibutton(app.GridTab, 'push');
            app.GridOKButton.Text = 'OK';
            app.GridLoadButton = uibutton(app.GridTab, 'push');
            app.GridLoadButton.Text = 'Load Grid';
            app.GridSaveOKButton = uibutton(app.GridTab, 'push');
            app.GridSaveOKButton.Text = 'Save as';
            app.GridNameEditFieldLabel = uilabel(app.GridTab);
            app.GridNameEditFieldLabel.Text = 'Grid Name';
            app.GridNameEditField = uieditfield(app.GridTab, 'text');
            app.GridNameEditField.Value = 'grid';
            app.GridDefaultsLabel = uilabel(app.GridTab);
            app.GridDefaultsLabel.Text = 'Grid Defaults';
            app.GridAppearencePanel = uipanel(app.GridTab);
            app.GridAppearencePanel.Title = 'Appearance';
            app.ColorDropDownLabel_2 = uilabel(app.GridAppearencePanel);
            app.ColorDropDownLabel_2.Text = 'Color';
            app.GridColorDropDown = uidropdown(app.GridAppearencePanel);
            app.MarkerLabel = uilabel(app.GridAppearencePanel);
            app.MarkerLabel.Text = 'Marker';
            app.GridMarkerDropDown = uidropdown(app.GridAppearencePanel);
            app.GridMarkerDropDown.Items = {'+', 'o', '*', '.', 'x', 'square', 'diamond', 'v', '^', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.GridMarkerDropDown.Value = '+';
            app.LineWidthEditFieldLabel_2 = uilabel(app.GridAppearencePanel);
            app.LineWidthEditFieldLabel_2.Text = 'Line Width';
            app.GridLineWidthEditField = uieditfield(app.GridAppearencePanel, 'numeric');
            app.GridLineWidthEditField.LowerLimitInclusive = 'off';
            app.GridLineWidthEditField.UpperLimitInclusive = 'off';
            app.GridLineWidthEditField.Limits = [0 Inf];
            app.GridLineWidthEditField.Value = 3;
            app.MarkerSizeEditFieldLabel_2 = uilabel(app.GridAppearencePanel);
            app.MarkerSizeEditFieldLabel_2.Text = 'Marker Size';
            app.GridMarkerSizeEditField = uieditfield(app.GridAppearencePanel, 'numeric');
            app.GridMarkerSizeEditField.Value = 10;
            app.UseFixedAnchorPointCheckBox = uicheckbox(app.GridTab);
            app.UseFixedAnchorPointCheckBox.Text = 'Use Fixed Anchor Point';
            app.SaveButton = uibutton(app.ZMAPSettingsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RevertTabButton = uibutton(app.ZMAPSettingsUIFigure, 'push');
            app.RevertTabButton.ButtonPushedFcn = createCallbackFcn(app, @RevertTabButtonPushed, true);
            app.RevertTabButton.Text = 'Revert Tab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ZmapSettings

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZMAPSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZMAPSettingsUIFigure)
        end
    end
end