classdef range_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CatalogRangeSelectionUIFigure  matlab.ui.Figure
        DistributionsPanel             matlab.ui.container.Panel
        TimeAxes                       matlab.ui.control.UIAxes
        MagAxes                        matlab.ui.control.UIAxes
        DepthAxes                      matlab.ui.control.UIAxes
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        EventsincatalogLabel           matlab.ui.control.Label
        NQuakes                        matlab.ui.control.Label
        DateRangePanel                 matlab.ui.container.Panel
        EarliestEditFieldLabel         matlab.ui.control.Label
        EarliestEditField              matlab.ui.control.EditField
        LatestEditFieldLabel           matlab.ui.control.Label
        LatestEditField                matlab.ui.control.EditField
        MagnitudeRangePanel            matlab.ui.container.Panel
        MinMagEdit                     matlab.ui.control.NumericEditField
        MagLabel                       matlab.ui.control.Label
        MaxMagEdit                     matlab.ui.control.NumericEditField
        LocationRagesPanel             matlab.ui.container.Panel
        ZkmLabel                       matlab.ui.control.Label
        MinDepthEdit                   matlab.ui.control.NumericEditField
        MaxDepthEdit                   matlab.ui.control.NumericEditField
        XdegLabel                      matlab.ui.control.Label
        MaxLonEdit                     matlab.ui.control.NumericEditField
        MinLonEdit                     matlab.ui.control.NumericEditField
        MinLatEdit                     matlab.ui.control.NumericEditField
        MaxLatEdit                     matlab.ui.control.NumericEditField
        YdegLabel                      matlab.ui.control.Label
        applyButton                    matlab.ui.control.Button
        cancelButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        Catalog % Event catalog
        CurrDateDist % Description
        CurrMagDist 
        CurrDepthDist
        MagBins
        DateBins
        DepthBins
    end

    methods (Access = private)
    
        function mask=updateValues(app)
            mask = true(app.Catalog.Count,1);
            mask = mask & app.Catalog.Depth >= app.MinDepthEdit.Value & app.Catalog.Depth <= app.MaxDepthEdit.Value;
            mask = mask & app.Catalog.Magnitude >= app.MinMagEdit.Value & app.Catalog.Magnitude <= app.MaxMagEdit.Value;
            mask = mask & app.Catalog.Date >= datetime(app.EarliestEditField.Value) &...
                app.Catalog.Date <= datetime(app.LatestEditField.Value);
            mask = mask & app.Catalog.Longitude >= app.MinLonEdit.Value & app.Catalog.Longitude <= app.MaxLonEdit.Value;
            mask = mask & app.Catalog.Latitude >= app.MinLatEdit.Value & app.Catalog.Latitude <= app.MaxLatEdit.Value;
            app.CurrDateDist.Data=app.Catalog.Date(mask);
            app.CurrMagDist.Data=app.Catalog.Magnitude(mask);
            app.CurrDepthDist.Data=app.Catalog.Depth(mask);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, catalog)
            app.Catalog = catalog;
            disp('Need to fix this so that a catalog can be written back out! also update names')
            app.MagBins = floor(min(catalog.Magnitude)) :0.1: ceil(max(catalog.Magnitude));
                
            %plot initial values
            h=histogram(app.TimeAxes,catalog.Date, 'FaceColor',[.8 .8 .8], 'EdgeAlpha',.5);
            app.DateBins=h.BinEdges;
            app.TimeAxes.NextPlot = 'add';
            
            histogram(app.MagAxes,catalog.Magnitude,'BinEdges',app.MagBins,...
                 'FaceColor',[.8 .8 .8], 'EdgeAlpha',.5);
            app.MagAxes.NextPlot = 'add';
            
            h=histogram(app.DepthAxes,catalog.Depth, 'FaceColor',[.8 .8 .8], 'FaceAlpha',.5);
            app.DepthAxes.NextPlot = 'add';
            app.DepthBins=h.BinEdges;
            %plot current values
            app.CurrDateDist=histogram(app.TimeAxes,app.Catalog.Date, app.DateBins);
            app.CurrMagDist=histogram(app.MagAxes,app.Catalog.Magnitude, app.MagBins);
            app.CurrDepthDist=histogram(app.DepthAxes,app.Catalog.Depth, app.DepthBins);


            app.NameEditField.Value = catalog.Name;
            app.NQuakes.Text = string(catalog.Count);
            app.EarliestEditField.Value = string(min(catalog.Date), 'uuuu-MM-dd HH:mm:ss');
            app.LatestEditField.Value = string(max(catalog.Date), 'uuuu-MM-dd HH:mm:ss');
            app.MinMagEdit.Value = min(catalog.Magnitude);
            app.MaxMagEdit.Value = max(catalog.Magnitude);
            app.MinDepthEdit.Value = min(catalog.Depth);
            app.MaxDepthEdit.Value = max(catalog.Depth);
            app.MinLonEdit.Value = min(catalog.Longitude);
            app.MaxLonEdit.Value = max(catalog.Longitude);
            app.MinLatEdit.Value = min(catalog.Latitude);
            app.MaxLatEdit.Value = max(catalog.Latitude);
            
        end

        % Value changed function: MaxDepthEdit, MaxLatEdit, 
        % MaxLonEdit, MaxMagEdit, MinDepthEdit, MinLatEdit, MinLonEdit, 
        % MinMagEdit
        function RangeChanged(app, event)
            app.updateValues;
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
           app.Catalog.subset_in_place(app.updateValues());
           app.Catalog.Name = app.NameEditField.Value;
        end

        % Value changed function: EarliestEditField, LatestEditField
        function DateFieldValueChanged(app, event)
            value = event.Source.Value ;% app.EarliestEditField.Value;
            s=strsplit(value,["-" "/" " "]);
            if numel(s) ==1
                event.Source.Value = string(datetime(str2double(value),1,1),'uuuu-MM-dd HH:mm:ss');
                value=event.Source.Value;
            end
            try
                datetime(value);
                app.updateValues;
            catch
                event.Source.Value=event.PreviousValue;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CatalogRangeSelectionUIFigure = uifigure;
            app.CatalogRangeSelectionUIFigure.Name = 'Catalog Range Selection';
            app.DistributionsPanel = uipanel(app.CatalogRangeSelectionUIFigure);
            app.DistributionsPanel.Title = 'Distributions';
            app.TimeAxes = uiaxes(app.DistributionsPanel);
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'count')
            app.MagAxes = uiaxes(app.DistributionsPanel);
            xlabel(app.MagAxes, 'Magnitude')
            ylabel(app.MagAxes, 'count')
            app.DepthAxes = uiaxes(app.DistributionsPanel);
            xlabel(app.DepthAxes, 'Depth')
            ylabel(app.DepthAxes, 'count')
            app.NameEditFieldLabel = uilabel(app.CatalogRangeSelectionUIFigure);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.CatalogRangeSelectionUIFigure, 'text');
            app.EventsincatalogLabel = uilabel(app.CatalogRangeSelectionUIFigure);
            app.EventsincatalogLabel.Text = 'Events in catalog:';
            app.NQuakes = uilabel(app.CatalogRangeSelectionUIFigure);
            app.NQuakes.Text = '000';
            app.DateRangePanel = uipanel(app.CatalogRangeSelectionUIFigure);
            app.DateRangePanel.Title = 'Date Range';
            app.EarliestEditFieldLabel = uilabel(app.DateRangePanel);
            app.EarliestEditFieldLabel.Text = 'Earliest';
            app.EarliestEditField = uieditfield(app.DateRangePanel, 'text');
            app.EarliestEditField.ValueChangedFcn = createCallbackFcn(app, @DateFieldValueChanged, true);
            app.EarliestEditField.Value = '1970-01-01 00:00:00';
            app.LatestEditFieldLabel = uilabel(app.DateRangePanel);
            app.LatestEditFieldLabel.Text = 'Latest';
            app.LatestEditField = uieditfield(app.DateRangePanel, 'text');
            app.LatestEditField.ValueChangedFcn = createCallbackFcn(app, @DateFieldValueChanged, true);
            app.MagnitudeRangePanel = uipanel(app.CatalogRangeSelectionUIFigure);
            app.MagnitudeRangePanel.Title = 'Magnitude Range';
            app.MinMagEdit = uieditfield(app.MagnitudeRangePanel, 'numeric');
            app.MinMagEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MagLabel = uilabel(app.MagnitudeRangePanel);
            app.MagLabel.Text = '<= Mag <=';
            app.MaxMagEdit = uieditfield(app.MagnitudeRangePanel, 'numeric');
            app.MaxMagEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.LocationRagesPanel = uipanel(app.CatalogRangeSelectionUIFigure);
            app.LocationRagesPanel.Title = 'Location Rages';
            app.ZkmLabel = uilabel(app.LocationRagesPanel);
            app.ZkmLabel.Text = '<= Z [km] <=';
            app.MinDepthEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MinDepthEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MaxDepthEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MaxDepthEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.XdegLabel = uilabel(app.LocationRagesPanel);
            app.XdegLabel.Text = '<= X [deg] <=';
            app.MaxLonEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MaxLonEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MinLonEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MinLonEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MinLatEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MinLatEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.MaxLatEdit = uieditfield(app.LocationRagesPanel, 'numeric');
            app.MaxLatEdit.ValueChangedFcn = createCallbackFcn(app, @RangeChanged, true);
            app.YdegLabel = uilabel(app.LocationRagesPanel);
            app.YdegLabel.Text = '<= Y [deg] <=';
            app.applyButton = uibutton(app.CatalogRangeSelectionUIFigure, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            app.cancelButton = uibutton(app.CatalogRangeSelectionUIFigure, 'push');
            app.cancelButton.Text = 'cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = range_selector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CatalogRangeSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CatalogRangeSelectionUIFigure)
        end
    end
end