classdef topo_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DEMButton                      matlab.ui.control.Button
        VerticalExaggerationEditFieldLabel  matlab.ui.control.Label
        VerticalExaggerationEditField  matlab.ui.control.NumericEditField
        VectorMapButton                matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        axesmuiButton                  matlab.ui.control.Button
        darkenButton                   matlab.ui.control.Button
        brightenButton                 matlab.ui.control.Button
        ShowEQDropDownLabel            matlab.ui.control.Label
        ShowEQDropDown                 matlab.ui.control.DropDown
        ShowFaultsDropDownLabel        matlab.ui.control.Label
        ShowFaultsDropDown             matlab.ui.control.DropDown
        ShowSpecialObjDropDownLabel    matlab.ui.control.Label
        ShowSpecialObjDropDown         matlab.ui.control.DropDown
        ColorSchemeDropDownLabel       matlab.ui.control.Label
        ColorSchemeDropDown            matlab.ui.control.DropDown
        ShadingButtonGroup             matlab.ui.container.ButtonGroup
        interpButton                   matlab.ui.control.RadioButton
        flatButton                     matlab.ui.control.RadioButton
        BackgroundColorButtonGroup     matlab.ui.container.ButtonGroup
        blackButton                    matlab.ui.control.RadioButton
        whiteButton                    matlab.ui.control.RadioButton
        EqOnTopo                       matlab.ui.control.CheckBox
        MatchTopographyLabel           matlab.ui.control.Label
        FaultsOnTopo                   matlab.ui.control.CheckBox
        SpecOnTopo                     matlab.ui.control.CheckBox
        ShowCoastlinesDropDownLabel    matlab.ui.control.Label
        ShowCoastlinesDropDown         matlab.ui.control.DropDown
        CoastOnTopo                    matlab.ui.control.CheckBox
        DEMsourceDropDownLabel         matlab.ui.control.Label
        DEMsourceDropDown              matlab.ui.control.DropDown
        CoastlineResolutionDropDownLabel  matlab.ui.control.Label
        CoastlineResolutionDropDown    matlab.ui.control.DropDown
        drawmapButton                  matlab.ui.control.Button
        drawcolorbarButton             matlab.ui.control.Button
        ListBoxLabel                   matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        map_figure % handle to the map figure (plma)
        map_axis % Description
        tmap % topographic map
        maptype = "matrix" % 1: matrix map, 2: vector map
        coli % handle, from displaym
        border % was 'bor'
        tmapleg % Description
        gx % grid x
        gy % grid y
        colbar % handle to colorbar
        resmap % Description
        results % was handles.resu
        equ % Description
        faults % Description
        MapTypes = ["matrix", "vector"]
        spec % special features
        depths_special % special features Z
        depths_eq % earthquake Z
        depf % faults Z
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, borders, eqs, faults, coast, resu, gx, gy, s)
            app.brightenButton.UserData = .1;
            app.darkenButton.UserData = -.1;
            app.results = resu;
            app.border = borders;
            app.equ = eqs;
            app.faults = faults;
            app.coast = coast;
            app.gx = gx;
            app.gy = gy;
            
            if s == 1
                conres = struct2cell(handles.resu);
                inp = conres(2, 1, 1);
                for i = 2 : size(handles.resu, 2)
                    inp = [inp; conres(2, 1, i)];
                end
                app.ListBox.Items = inp;
            end
            app.drawmapButton.Visible = 'off';
            
        end

        % Value changed function: VerticalExaggerationEditField
        function VerticalExaggerationEditFieldValueChanged(app, event)
            value = app.VerticalExaggerationEditField.Value;
            daspectm('m', value);
            tightmap;
        end

        % Selection changed function: ShadingButtonGroup
        function ShadingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShadingButtonGroup.SelectedObject;
            shading(selectedButton.Text);
        end

        % Button pushed function: brightenButton, darkenButton
        function brightendarkenButtonPushed(app, event)
            value = event.Source.UserData;
            brighten(app.map_figure, value);
        end

        % Selection changed function: BackgroundColorButtonGroup
        function BackgroundColorButtonGroupSelectionChanged(app, event)
            selectedButton = app.BackgroundColorButtonGroup.SelectedObject;
            whitebg(app.map_figure, selectedButton.Text);
        end

        % Button pushed function: axesmuiButton
        function axesmuiButtonPushed(app, event)
            axesmui(app.map_axis);
        end

        % Value changed function: ColorSchemeDropDown
        function ColorSchemeDropDownValueChanged(app, event)
            value = app.ColorSchemeDropDown.Value;
            
            switch value
                case 'grayscale 1'
                    demcmap(app.tmap, 265, [1 1 1], [.3 .3 .3; .8 .8 .8]);
                case 'grayscale 2'
                    demcmap(app.tmap, 265, [1 1 1], [.5 .5 .5; .8 .8 .8]);
                otherwise
                    if min(app.tmap(:)) > 0
                        demcmap( app.tmap, 100, [0 0.3 1], []);
                    end
            end
            daspectm('m', 05);
        end

        % Button pushed function: VectorMapButton
        function VectorMapButtonPushed(app, event)
            inp = app.CoastlineResolutionDropDown.Value; % one of: "crude", "low", "intermediate", "high"
            app.map_figure = figure('Name', 'Topographic Map', 'NumberTitle', 'off', ...
                'Color', [1 1 1], 'Visible', 'off');
            
            app.map_axis = axesm('MapProjection', 'eqdcylin');
            set(app.map_figure, 'Visible', 'on');
            
            latlim = app.border([3, 4]);
            lonlim = app.border([1, 2]);
            
            switch inp
                case "crude"
                    % do nothing
                case "low"
                    fn = 'gshhs_c.b';
                case "intermediate"
                    fn = 'gshhs_i.b';
                case "high"
                    fn = 'gshhs_h.b';
            end
            if inp == "crude"
                load worldlo; % File no longer exists...
                displaym(POline);
                delete(handlem('International Boundary'));
            else
                GSHHS(fn, 'createindex');
                vdata = gshhs(fn, latlim, lonlim);
                coli = displaym(vdata); % possibly use geoshow() instead!
                set(coli, 'FaceColor', [1 1 1]);
            end
            tickformula = @(lims) abs(abs(lims(1)) - abs(lims(2))) / 4;
            tilat = tickformula(latlim);
            tilon = tickformula(lonlim);
            setm(app.map_axis, 'maplatlimit', latlim, 'maplonlimit', lonlim);
            setm(app.map_axis, 'meridianlabel', 'on', 'parallellabel', 'on', ...
                'plinelocation', tilat, 'mlinelocation', tilon, ...
                'glinestyle', '-.', ...
                'grid', 'off', ...
                'plabellocation', tilat, 'mlabellocation', tilon, ...
                'LabelFormat', 'compass', ...
                'flinewidth', 3);
            showaxes('hide');
            app.maptype = "vector";
            app.coli = coli;
            app.VectorMapButton.Text = 'NEW Vector Map';
            app.drawmapButton.Visible = 'on';
            maptool;
        end

        % Button pushed function: drawmapButton
        function drawmapButtonPushed(app, event)
            disp('this is topo|draw')
            
            % TOFIX: change following from handles to app
            resu = handles.resu;
            figure(app.map_figure);
            resmap = app.resmap;
            
            if resmap > 1
                delete(resmap);
            end
            
            if app.maptype == "matrix" % matrix map
                colorbar = app.colbar;
            end
            
            if app.maptype == "vector" % vector map
                app.colbar = colorbar;
                set(app.colbar, 'Position', [0.9 0.3 0.015 .3], 'visible', 'on', ...
                    'FontSize', 10, 'FontWeight', 'normal', ...
                    'FontWeight', 'normal', 'LineWidth', 1, ...
                    'Box', 'on', 'TickDir', 'out');
            end
            
            inp = get(handles.listres, 'Value');
            ren = resu(inp).data;
            figure(app.map_figure);
            cm = colormap(app.map_axis, 'jet');
            if app.maptype == "matrix" %matrix map
                [lat, lon] = meshgrat(app.tmap, app.tmapleg);
                [X, Y]  = meshgrid(app.gx, app.gy);
                ren = interp2(X, Y, ren, lon, lat);
                cmin = min(ren(:));
                cmax = max(ren(:));
                cmin = min(min(resu(inp).data));
                cmax = max(max(resu(inp).data));
                ren(isnan(ren)) = min(ren(:)) - 20;
                
                ren(app.tmap < 0 & ren < 0) = 20;
                app.resmap = meshm(ren, app.tmapleg, size(app.tmap), app.tmap);
                daspectm('m', 05);
                tightmap;
                view([0 90]);
                camlight;
                lighting phong;
                set(gca, 'projection', 'perspective');
                end_color = [ 0.85 0.9 0.9 ];
                cm = [ end_color; cm; end_color ];
                caxis([ cmin cmax ]);
                shading_type = 'flat';
            end
            
            if handles.maptype == "vector" %vector map
                app.resmap = pcolorm(ren);
                zdatam(handlem('allline'))
                shading_type = 'interp';
            end
            colormap(app.map.axis, cm);
            app.colbar = colorbar;
            delete(colorbar);
            shading(shading_type);
            app.drawmapButton.Visible = 'off';
        end

        % Value changed function: ShowEQDropDown
        function ShowEQDropDownValueChanged(app, event)
            value = app.ShowEQDropDown.Value;
            
            if value == "no"
                if ~isempty(app.ploe)
                    delete(app.ploe);
                end
                app.ploe = [];
                app.depths_eq = [];
                return
            end
            
            figure(app.map_figure);
            
            plotOnTopo = app.EqOnTopo && app.maptype == "matrix";
            if isempty(app.depths_eq)  &&  plotOnTopo
                [lat, lon] = meshgrat(app.tmap, app.tmapleg);
                app.depths_eq = interp2(lon, lat, app.tmap, app.equ.Longitude, app.equ.Latitude);
                close(hw);
            end
            
            if endsWith(value,'as dot')
                marker = 'o'; % [SIC]  maybe should be '.'
                markersize = 3;
                markeredgecolor = 'r';
            elseif endsWith(value,'as circle')
                marker = 'o';
                markersize = 2;
                markeredgecolor = 'k';
            end
            
            if plotOnTopo
                app.ploe = plot3m(app.equ.Latitude, app.equ.Longitude, app.depths_eq+25, ['r', marker]);
            else
                app.ploe = plotm(app.equ.Latitude, app.equ.Longitude, ['r', marker]);
                if handles.maptype == "matrix"
                    zdatam(handlem('allline'), max(app.tmap(:)));
                end
            end
            
            app.ploe.LineWidth = 0.1;
            app.ploe.MarkerSize = markersize;
            app.ploe.MarkerFaceColor = 'w';
            app.ploe.MarkerEdgeColor = markeredgecolor;
        end

        % Value changed function: ShowFaultsDropDown
        function ShowFaultsDropDownValueChanged(app, event)
            value = app.ShowFaultsDropDown.Value;
            
            if value == "no"
                if ~isempty(app.plof)
                    delete(app.plof)
                end
                app.plof = [];
                app.depf = [];
                return;
            end
            if isempty(app.depf) &&  handles.maptype == "matrix"
                [lat, lon] = meshgrat(app.tmap, app.tmapleg);
                app.depf =  interp2(lon, lat, app.tmap, app.faults(:, 1), app.faults(:, 2));
            end
            
            figure(app.map_figure);
            
            plotOnTopo = app.FaultsOnTopo && app.maptype == "matrix";
            if plotOnTopo
                app.plof = plot3m(app.faults(:, 2), app.faults(:, 1), app.depf+25, 'm', 'Linewidth', 2);
            else
                app.plof = plotm(app.faults(:, 2), app.faults(:, 1), 'm', 'Linewidth', 2);
                if handles.maptype == "matrix"
                    zdatam(handlem('allline'), max(app.tmap(:)));
                end
            end
        end

        % Value changed function: ShowSpecialObjDropDown
        function ShowSpecialObjDropDownValueChanged(app, event)
            value = app.ShowSpecialObjDropDown.Value;
            
            if value == "no"
                if ~isempty(app.plos)
                    delete(app.plos)
                end
                app.plos = [];
                app.depths_special = [];
                return;
            end
            
            if contains(value,'star')
                marker = '*';
            elseif contains(value,'triangle')
                marker = 'v';
            end
            
            plotOnTopo = app.SpecOnTopo.Value && app.maptype == "matrix";
            
            s = app.spec;
            
            [file1, path1] = uigetfile([ '*.txt'], 'File containing  my, mx ');
            s = load(fullfile(path1,file1));
            
            figure_w_normalized_uicontrolunits(app.map_figure);
            
            if isempty(app.depths_special)  &&  plotOnTopo
                [lat, lon] = meshgrat(app.tmap, app.tmapleg);
                app.depths_special = interp2(lat, lon, app.tmap, s(:, 1), s(:, 2));
            end
            if plotOnTopo
                app.plos = plot3m(s(:, 1), s(:, 2), app.depths_special+25);
            else
                app.plos = plotm(s(:, 1), s(:, 2));
                if handles.maptype == "matrix"
                    zdatam(handlem('allline'), max(app.tmap(:)));
                end
            end
            
            app.plos.Marker = marker;
            app.plos.LineWidth = 0.1;
            app.plos.MarkerSize = 3;
            app.plos.MarkerFaceColor = 'w';
            app.plos.MarkerEdgeCOlor = 'r';
            
        end

        % Button pushed function: drawcolorbarButton
        function drawcolorbarButtonPushed(app, event)
            resu = app.results;
            inp = get(handles.listres, 'Value');
            ret = resu(inp).lab;
            
            app.colbar = colorbar;
            set(app.colbar, 'Position', [0.9 0.3 0.015 .3])
            set(app.colbar, 'visible', 'on', 'FontSize', 10, 'FontWeight', 'normal', ...
                'FontWeight', 'normal', 'LineWidth', 1, 'Box', 'on', 'TickDir', 'out');
            colorbar;
            
            txt1 = text('Units', 'normalized', 'Position', [ 1.2 0.7 0 ], 'HorizontalAlignment', 'right', ...
                'FontSize', 10, 'FontWeight', 'normal', 'String', ret);
        end

        % Button pushed function: DEMButton
        function DEMButtonPushed(app, event)
            value = app.DEMsourceDropDown.Value;
            bor = app.border;
            app.map_figure = figure( ...
                'Name'          , 'Topographic Map', ...
                'NumberTitle'   , 'off', ...
                'Color'         , [1 1 1], ...
                'Visible'       , 'off');
            
            app.map_axis = axesm('MapProjection', 'eqdcylin');
            figure(app.map_figure);
            set(app.map_figure, 'Visible', 'on');
            
            lo1 = bor(1); lo2 = bor(2);
            la1 = bor(3); la2 = bor(4);
            fac = 1;
            if abs(la2-la1) > 10 || abs(lo2-lo1) > 10
                def = {'3'};
                ni2 = inputdlg('Decimation factor for DEM data?', 'Input', 1, def);
                l = ni2{:};
                fac = str2double(l);
            end
            switch(value)
                case "30 arc sec resolution (GTOPO30)"
                    [app.tmap, app.tmapleg, latlim, lonlim] = loadpgt30([la1 la2], [lo1 lo2], fac);
                    
                case "GLOBE Digital Elevation Map"
                    [app.tmap, app.tmapleg, lonlim] = loadglobedem([la1 la2], [lo1 lo2], fac);
                    
                case "2 deg resolution"
                    [app.tmap, app.tmapleg, lonlim] = load2degdem([la1 la2], [lo1 lo2], fac);
                    
                case "5 deg resolution"
                    [app.tmap, app.tmapleg, lonlim] = load5degdem([la1 la2], [lo1 lo2], fac);
            end
            
            
            meshm(app.tmap, app.tmapleg, size(app.tmap), app.tmap);
            demcmap(app.tmap);
            setm(app.map_axis, 'maplatlimit', latlim, 'maplonlimit', lonlim);
            
            if min(app.tmap(:)) > 0
                demcmap(app.tmap, 100, [0, 0.3, 1], []);
            end
            daspectm('m', 05);
            
            showaxes('hide');
            
            toticks = @(x1, x2) (x1 + [0.5, 1.5, 2.5, 3.5]) * abs((x2-x1)/4);
            tilat = toticks(la1, la2)';
            tilon = toticks(lo1, lo2)';
            
            setm(app.map_axis, 'meridianlabel', 'on', 'parallellabel', 'on', ...
                'LabelUnits', 'dm', ...
                'mlabelround', -2, ...
                'plabelround', -2, ...
                'grid', 'off', ...
                'LabelFormat', 'compass', ...
                'plabellocation', tilat, 'mlabellocation', tilon, ...
                'plinelocation', tilat, 'mlinelocation', tilon, ...
                'frame', 'off');
            shading flat
            
            zdatam(handlem('mlabel'), 0);
            zdatam(handlem('plabel'), 0);
            camlight(-80, 0);
            lighting phong;
            material([.8, 1, 0]);
            
            app.colbar = colorbar;
            set(app.colbar, 'Position', [0.9, 0.3, 0.015, 0.3], 'visible', 'on', ...
                'FontSize', 10, 'FontWeight', 'normal', ...
                'FontWeight', 'normal', 'LineWidth', 1, ...
                'Box', 'on', 'TickDir', 'out');
            
            app.DEMButton.Text = 'NEW DEM';
            app.maptype = "matrix";
            maptool;
            app.drawmapButton.Visible = 'on';
            
            function [tm, tl, latlim, lonlim] = loadpgt30(latlims, lonlims, decimation_factor)
                global pgt30
                gtopo30s(latlims, lonlims);
                [tm, tl] = gtopo302(pgt30, decimation_factor, latlims, lonlims);
                tm(isnan(tm)) = -1; %Replace the NaNs in the ocean with -1 to color them blue
                [latlim, lonlim] = limitm(tm, tl);
            end
            
            function [tm, tl, latlim, lonlim] = loadglobedem(latlims, lonlims, decimation_factor)
                myfname = globedems(latlims, lonlims);
                [tm, tl] = globedem(myfname{1}, decimation_factor, latlims, lonlims);
                tm(isnan(tm)) = nan; %Replace the NaNs in the ocean with -1 to color them blue
                [latlim, lonlim] = limitm(tm, tl);
            end
            
            function [tm, tl, latlim, lonlim] = load2degdem(latlim, lonlim, decimation_factor)
                [lat, lon, gtmap] = satbath(decimation_factor, latlims, lonlims); % general matrix map
                gtmap(isnan(gtmap)) = -1;
                pack
                [map, maplegend] = nanm(latlim, lonlim, 30);
                % original was about 1 cell per degree
                tm = imbedm(lat, lon, gtmap, map, maplegend);
                tl = maplegend;
            end
            
            function [tm, tl, latlim, lonlim] = load5degdem(latlims, lonlims, decimation_factor)
                [tm, tl] = tbase(decimation_factor, latlims, lonlims);
                [latlim, lonlim] = limitm(tm, tl);
                tm(isnan(tm)) = -1; %Replace the NaNs in the ocean with -1 to color them blue
            end
        end

        % Value changed function: ShowCoastlinesDropDown
        function ShowCoastlinesCheckBoxValueChanged(app, event)
            value = app.ShowCoastlinesCheckBox.Value;
            
            if value == "yes"
                if ~isempty(app.ploc)
                    delete(app.ploc)
                end
                app.ploc = [];
            else
                app.ploc = plot3m(app.coast(:, 2), app.coast(:, 1), 1, 'k', 'Linewidth', 2);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DEMButton = uibutton(app.UIFigure, 'push');
            app.DEMButton.ButtonPushedFcn = createCallbackFcn(app, @DEMButtonPushed, true);
            app.DEMButton.Text = 'DEM';
            app.VerticalExaggerationEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalExaggerationEditFieldLabel.Text = 'Vertical Exaggeration';
            app.VerticalExaggerationEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalExaggerationEditField.Limits = [0 Inf];
            app.VerticalExaggerationEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalExaggerationEditFieldValueChanged, true);
            app.VectorMapButton = uibutton(app.UIFigure, 'push');
            app.VectorMapButton.ButtonPushedFcn = createCallbackFcn(app, @VectorMapButtonPushed, true);
            app.VectorMapButton.Text = 'Vector Map';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.axesmuiButton = uibutton(app.Panel, 'push');
            app.axesmuiButton.ButtonPushedFcn = createCallbackFcn(app, @axesmuiButtonPushed, true);
            app.axesmuiButton.Text = 'axesmui';
            app.darkenButton = uibutton(app.Panel, 'push');
            app.darkenButton.ButtonPushedFcn = createCallbackFcn(app, @brightendarkenButtonPushed, true);
            app.darkenButton.Text = 'darken';
            app.brightenButton = uibutton(app.Panel, 'push');
            app.brightenButton.ButtonPushedFcn = createCallbackFcn(app, @brightendarkenButtonPushed, true);
            app.brightenButton.Text = 'brighten';
            app.ShowEQDropDownLabel = uilabel(app.Panel);
            app.ShowEQDropDownLabel.Text = 'Show EQ';
            app.ShowEQDropDown = uidropdown(app.Panel);
            app.ShowEQDropDown.Items = {'as dot', 'as circle', 'no'};
            app.ShowEQDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowEQDropDownValueChanged, true);
            app.ShowEQDropDown.Value = 'as dot';
            app.ShowFaultsDropDownLabel = uilabel(app.Panel);
            app.ShowFaultsDropDownLabel.Text = 'Show Faults';
            app.ShowFaultsDropDown = uidropdown(app.Panel);
            app.ShowFaultsDropDown.Items = {'yes', 'no'};
            app.ShowFaultsDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowFaultsDropDownValueChanged, true);
            app.ShowFaultsDropDown.Value = 'yes';
            app.ShowSpecialObjDropDownLabel = uilabel(app.Panel);
            app.ShowSpecialObjDropDownLabel.Text = 'Show Special Obj';
            app.ShowSpecialObjDropDown = uidropdown(app.Panel);
            app.ShowSpecialObjDropDown.Items = {'as star', 'as triangle (v)', 'no'};
            app.ShowSpecialObjDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowSpecialObjDropDownValueChanged, true);
            app.ShowSpecialObjDropDown.Value = 'as star';
            app.ColorSchemeDropDownLabel = uilabel(app.Panel);
            app.ColorSchemeDropDownLabel.Text = 'Color Scheme';
            app.ColorSchemeDropDown = uidropdown(app.Panel);
            app.ColorSchemeDropDown.Items = {'grayscale 1', 'grayscale 2', 'colormap (decmap)'};
            app.ColorSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorSchemeDropDownValueChanged, true);
            app.ColorSchemeDropDown.Value = 'grayscale 1';
            app.ShadingButtonGroup = uibuttongroup(app.Panel);
            app.ShadingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShadingButtonGroupSelectionChanged, true);
            app.ShadingButtonGroup.Title = 'Shading';
            app.interpButton = uiradiobutton(app.ShadingButtonGroup);
            app.interpButton.Text = 'interp';
            app.interpButton.Value = true;
            app.flatButton = uiradiobutton(app.ShadingButtonGroup);
            app.flatButton.Text = 'flat';
            app.BackgroundColorButtonGroup = uibuttongroup(app.Panel);
            app.BackgroundColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BackgroundColorButtonGroupSelectionChanged, true);
            app.BackgroundColorButtonGroup.Title = 'Background Color';
            app.blackButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.blackButton.Text = 'black';
            app.blackButton.Value = true;
            app.whiteButton = uiradiobutton(app.BackgroundColorButtonGroup);
            app.whiteButton.Text = 'white';
            app.EqOnTopo = uicheckbox(app.Panel);
            app.EqOnTopo.Text = '';
            app.MatchTopographyLabel = uilabel(app.Panel);
            app.MatchTopographyLabel.Text = {'Match'; 'Topography'};
            app.FaultsOnTopo = uicheckbox(app.Panel);
            app.FaultsOnTopo.Text = '';
            app.SpecOnTopo = uicheckbox(app.Panel);
            app.SpecOnTopo.Text = '';
            app.ShowCoastlinesDropDownLabel = uilabel(app.Panel);
            app.ShowCoastlinesDropDownLabel.Text = 'Show Coastlines';
            app.ShowCoastlinesDropDown = uidropdown(app.Panel);
            app.ShowCoastlinesDropDown.Items = {'yes', 'no'};
            app.ShowCoastlinesDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowCoastlinesCheckBoxValueChanged, true);
            app.ShowCoastlinesDropDown.Value = 'yes';
            app.CoastOnTopo = uicheckbox(app.Panel);
            app.CoastOnTopo.Enable = 'off';
            app.CoastOnTopo.Text = '';
            app.DEMsourceDropDownLabel = uilabel(app.UIFigure);
            app.DEMsourceDropDownLabel.Text = 'DEM source';
            app.DEMsourceDropDown = uidropdown(app.UIFigure);
            app.DEMsourceDropDown.Items = {'30 arc sec resolution (GTOPO30)', 'GLOBE Digital Elevation Map', '2 deg resolution', '5 deg resolution'};
            app.DEMsourceDropDown.Value = '30 arc sec resolution (GTOPO30)';
            app.CoastlineResolutionDropDownLabel = uilabel(app.UIFigure);
            app.CoastlineResolutionDropDownLabel.Text = 'Coastline Resolution';
            app.CoastlineResolutionDropDown = uidropdown(app.UIFigure);
            app.CoastlineResolutionDropDown.Items = {'crude', 'low', 'intermediate', 'high'};
            app.CoastlineResolutionDropDown.Value = 'crude';
            app.drawmapButton = uibutton(app.UIFigure, 'push');
            app.drawmapButton.ButtonPushedFcn = createCallbackFcn(app, @drawmapButtonPushed, true);
            app.drawmapButton.Text = 'draw map';
            app.drawcolorbarButton = uibutton(app.UIFigure, 'push');
            app.drawcolorbarButton.ButtonPushedFcn = createCallbackFcn(app, @drawcolorbarButtonPushed, true);
            app.drawcolorbarButton.Text = 'draw colorbar';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = topo_selector(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end